classdef App_Pendulo_Simple < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ErrorSolSimuTextArea        matlab.ui.control.TextArea
        ErrorSolSimuTextAreaLabel   matlab.ui.control.Label
        ErrorSolAproxTextArea       matlab.ui.control.TextArea
        ErrorSolAproxTextAreaLabel  matlab.ui.control.Label
        FreAngularTextArea          matlab.ui.control.TextArea
        FreAngularTextAreaLabel     matlab.ui.control.Label
        AngInicialTextArea          matlab.ui.control.TextArea
        AngInicialTextAreaLabel     matlab.ui.control.Label
        AmplitudTextArea            matlab.ui.control.TextArea
        AmplitudTextAreaLabel       matlab.ui.control.Label
        SolSimulinkButton           matlab.ui.control.Button
        SolAproxButton              matlab.ui.control.Button
        AnguloDropDown              matlab.ui.control.DropDown
        AnguloDropDownLabel         matlab.ui.control.Label
        LeerdatosButton             matlab.ui.control.Button
        DatosDropDown               matlab.ui.control.DropDown
        DatosDropDownLabel          matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DatosDropDown
        function DatosDropDownValueChanged(app, event)
            Datos = app.DatosDropDown.Value;
        end

        % Button pushed function: LeerdatosButton
        function LeerdatosButtonPushed(app, event)
            Datos = app.DatosDropDown.Value;
            Angulo = app.AnguloDropDown.Value;
            data_pq = readtable('pendulo_2_pq.csv');
            data_gr = readtable('pendulo_2_gr.csv');
            Data_t = data_pq(4:61,"Var1");
            Data_tiempo = table2array(Data_t);
            Data_pq_deg = data_pq(4:61,"Var2");
            Data_pq_rad = deg2rad(table2array(Data_pq_deg));
            Theta0_pq = (max(Data_pq_rad)-min(Data_pq_rad))/2
            yData_pq_rad = Data_pq_rad + Theta0_pq;
            Data_gr_deg = data_gr(4:61,"Var2");
            Data_gr_rad = deg2rad(table2array(Data_gr_deg));
            Theta0_gr = (max(Data_gr_rad)-min(Data_gr_rad))/2
            yData_gr_rad = Data_gr_rad + Theta0_gr;

            if Datos == 2
            if Angulo == 2
                grid on 
                hold on
                plot(app.UIAxes,Data_tiempo,yData_pq_rad,'ro')
                title (app.UIAxes,'Posición Angular (Angulo Pequeño)')
                legend(app.UIAxes,'D. expe');
                xlabel(app.UIAxes,'Tiempo [s]');
                ylabel(app.UIAxes,'Posición angular [rad]');
            end
            if Angulo == 3
                grid on 
                hold on
                plot(app.UIAxes,Data_tiempo,yData_gr_rad,'ro')
                title (app.UIAxes,'Posición Angular (Angulo Grande)')
                legend(app.UIAxes,'D. expe');
                xlabel(app.UIAxes,'Tiempo [s]');
                ylabel(app.UIAxes,'Posición angular [rad]');
            end
            end 
            if Datos == 3
            if Angulo == 2
                grid on 
                hold on
                Data_Rapidez_pq = data_pq(4:61,"Var3");
                Data_Rapidez_pq_rad = deg2rad(table2array(Data_Rapidez_pq));
                plot(app.UIAxes,Data_tiempo,Data_Rapidez_pq_rad,'ro')
                title (app.UIAxes,'Rapidez Angular (Angulo Pequeño)')
                legend('D. expe','Sol. Aprox','Simulik');
                xlabel(app.UIAxes,'Tiempo [s]');
                ylabel(app.UIAxes,'Rapidez angular [rad/s]');
            end
            if Angulo == 3
                grid on 
                hold on
                Data_Rapidez_gr = data_gr(4:61,"Var3");
                Data_Rapidez_gr_rad = deg2rad(table2array(Data_Rapidez_gr));
                plot(app.UIAxes,Data_tiempo,Data_Rapidez_gr_rad,'ro')
                title (app.UIAxes,'Rapidez Angular(Angulo Grande)')
                legend('D. expe','Sol. Aprox','Simulik');
                xlabel(app.UIAxes,'Tiempo [s]');
                ylabel(app.UIAxes,'Rapidez angular [rad/s]');
            end
            end
        end

        % Value changed function: AnguloDropDown
        function AnguloDropDownValueChanged(app, event)
            Angulo = app.AnguloDropDown.Value;
            
        end

        % Button pushed function: SolAproxButton
        function SolAproxButtonPushed(app, event)
            Datos = app.DatosDropDown.Value;
            Angulo = app.AnguloDropDown.Value;
            data_pq = readtable('pendulo_2_pq.csv');
            data_gr = readtable('pendulo_2_gr.csv');
            Data_t = data_pq(4:61,"Var1");
            Data_tiempo = table2array(Data_t);
            Data_pq_deg = data_pq(4:61,"Var2");
            Data_pq_rad = deg2rad(table2array(Data_pq_deg));
            Theta0_pq = (max(Data_pq_rad)-min(Data_pq_rad))/2
            yData_pq_rad = Data_pq_rad + Theta0_pq;
            Data_gr_deg = data_gr(4:61,"Var2");
            Data_gr_rad = deg2rad(table2array(Data_gr_deg));
            Theta0_gr = (max(Data_gr_rad)-min(Data_gr_rad))/2
            yData_gr_rad = Data_gr_rad + Theta0_gr;
            Data_Rapidez_pq = data_pq(4:61,"Var3");
            Data_Rapidez_gr = data_gr(4:61,"Var3");
            Data_Rapidez_pq_rad = deg2rad(table2array(Data_Rapidez_pq));
            Data_Rapidez_gr_rad = deg2rad(table2array(Data_Rapidez_gr));

            if Datos == 2
                if Angulo == 2
                    Phi    = pi/2
                    Thetam_pq = Theta0_pq/sin(Phi)
                    w_n_pq = 5.513438707261346
                    F = (Thetam_pq)*sin(w_n_pq*Data_tiempo + Phi);
                    grid on
                    hold on
                    plot(app.UIAxes,Data_tiempo,yData_pq_rad,'ro',Data_tiempo,F,'b')
                    legend(app.UIAxes,'D. expe','Sol. Aprox');
                    app.AmplitudTextArea.Value = string(Thetam_pq )
                    app.AngInicialTextArea.Value = string(Theta0_pq)
                    app.FreAngularTextArea.Value = string(w_n_pq)
                    Area_DataExpe_P   = abs(trapz(Data_tiempo,yData_pq_rad));
                    Area_DataSolAp_P  = abs(trapz(Data_tiempo,F));
                    ErrorSolAp_P      = abs(((Area_DataSolAp_P - Area_DataExpe_P)/Area_DataSolAp_P)*100)
                    app.ErrorSolAproxTextArea.Value = string(ErrorSolAp_P)

                end
                if Angulo == 3
                    Phi    = pi/2
                    Thetam_gr = Theta0_gr/sin(Phi)
                    w_n_gr = 4.621076207881561
                    F = (Thetam_gr)*sin(w_n_gr*Data_tiempo + Phi);
                    grid on 
                    hold on
                    plot(app.UIAxes,Data_tiempo,yData_gr_rad,'ro',Data_tiempo,F,'b')
                    legend(app.UIAxes,'D. expe','Sol. Aprox');
                    app.AmplitudTextArea.Value = string(Thetam_gr)
                    app.AngInicialTextArea.Value = string(Theta0_gr)
                    app.FreAngularTextArea.Value = string(w_n_gr)
                    Area_DataExpe_P   = abs(trapz(Data_tiempo,yData_gr_rad));
                    Area_DataSolAp_P  = abs(trapz(F));
                    ErrorSolAp_P      = abs(((Area_DataSolAp_P - Area_DataExpe_P)/Area_DataSolAp_P)*100)
                    app.ErrorSolAproxTextArea.Value = string(ErrorSolAp_P)
                    
                end
            end
            if Datos == 3
                if Angulo == 2
                    Phi    = pi/2
                    Thetam_pq = Theta0_pq/sin(Phi)
                    w_n_pq = 5.513438707261346
                    Rapidez = (Thetam_pq)*w_n_pq*cos(w_n_pq*Data_tiempo + Phi);
                    grid on 
                    hold on
                    plot(app.UIAxes,Data_tiempo,Data_Rapidez_pq_rad ,'ro',Data_tiempo,Rapidez,'b')
                    legend(app.UIAxes,'D. expe','Sol. Aprox');
                    Amplitud_pq = Thetam_pq*w_n_pq
                    app.AmplitudTextArea.Value = string(Amplitud_pq)
                    app.AngInicialTextArea.Value = string(Theta0_pq)
                    app.FreAngularTextArea.Value = string(w_n_pq)
                    Area_DataExpe_V   = abs(trapz(Data_tiempo,Data_Rapidez_pq_rad));
                    Area_DataSolAp_V  = abs(trapz(Data_tiempo,Rapidez));
                    ErrorSolAp_V      = abs(((Area_DataSolAp_V - Area_DataExpe_V)/Area_DataSolAp_V)*100)
                    app.ErrorSolAproxTextArea.Value = string(ErrorSolAp_V)
                    
                end
                if Angulo == 3
                    Phi    = pi/2
                    Thetam_gr = Theta0_gr/sin(Phi)
                    w_n_gr = 4.621076207881561
                    Rapidez = (Thetam_gr)*w_n_gr*cos(w_n_gr*Data_tiempo + Phi);
                    grid on
                    hold on
                    plot(app.UIAxes,Data_tiempo,Data_Rapidez_gr_rad ,'ro',Data_tiempo,Rapidez,'b')
                    legend(app.UIAxes,'D. expe','Sol. Aprox');
                    Amplitud_gr = Thetam_gr*w_n_gr
                    app.AmplitudTextArea.Value = string(Amplitud_gr)
                    app.AngInicialTextArea.Value = string(Theta0_gr)
                    app.FreAngularTextArea.Value = string(w_n_gr)
                    Area_DataExpe_V   = abs(trapz(Data_tiempo,Data_Rapidez_gr_rad));
                    Area_DataSolAp_V  = abs(trapz(Rapidez));
                    ErrorSolAp_V      = abs(((Area_DataSolAp_V - Area_DataExpe_V)/Area_DataSolAp_V)*100)
                    app.ErrorSolAproxTextArea.Value = string(ErrorSolAp_V)
                end
            end  
        end

        % Button pushed function: SolSimulinkButton
        function SolSimulinkButtonPushed(app, event)
            Datos = app.DatosDropDown.Value;
            Angulo = app.AnguloDropDown.Value;
            data_pq = readtable('pendulo_2_pq.csv');
            data_gr = readtable('pendulo_2_gr.csv');
            Data_t = data_pq(4:61,"Var1");
            Data_tiempo = table2array(Data_t);
            Data_pq_deg = data_pq(4:61,"Var2");
            Data_pq_rad = deg2rad(table2array(Data_pq_deg));
            Theta0_pq = (max(Data_pq_rad)-min(Data_pq_rad))/2
            yData_pq_rad = Data_pq_rad + Theta0_pq;
            Data_gr_deg = data_gr(4:61,"Var2");
            Data_gr_rad = deg2rad(table2array(Data_gr_deg));
            Theta0_gr = (max(Data_gr_rad)-min(Data_gr_rad))/2
            yData_gr_rad = Data_gr_rad + Theta0_gr;
            Data_Rapidez_pq = data_pq(4:61,"Var3");
            Data_Rapidez_gr = data_gr(4:61,"Var3");
            Data_Rapidez_pq_rad = deg2rad(table2array(Data_Rapidez_pq));
            Data_Rapidez_gr_rad = deg2rad(table2array(Data_Rapidez_gr));
            ODE_pq = sim("Pendulo_No_Lineal.slx")
            Tiempo_pq = ODE_pq.ScopeData(:,1);
            PosicionODE_pq = ODE_pq.ScopeData(:,2);
            RapidezODE_pq = ODE_pq.ScopeData1(:,2);
            ODE_gr= sim("Pendulo_No_Lineal_gr.slx");
            Tiempo_gr= ODE_gr.ScopeData(:,1);
            PosicionODE_gr = ODE_gr.ScopeData(:,2);
            RapidezODE_gr = ODE_gr.ScopeData1(:,2);

            if Datos == 2
                if Angulo == 2
                    Phi    = pi/2
                    Thetam_pq = Theta0_pq/sin(Phi)
                    w_n_pq = 5.513438707261346
                    F = (Thetam_pq)*sin(w_n_pq*Data_tiempo + Phi);
                    grid on
                    hold on
                    plot(app.UIAxes,Data_tiempo,yData_pq_rad,'ro',Data_tiempo,F,'b',Tiempo_pq,PosicionODE_pq,'g')
                    legend(app.UIAxes,'D. expe','Sol. Aprox','Simulik');
                    Area_DataExpe_P   = abs(trapz(Data_tiempo,yData_pq_rad));
                    Area_DataODE_P    = abs(trapz(Tiempo_pq,PosicionODE_pq));
                    ErrorODE_P        = abs(((Area_DataODE_P - Area_DataExpe_P)/Area_DataODE_P)*100)
                    app.ErrorSolSimuTextArea.Value = string(ErrorODE_P)

                end
                if Angulo == 3
                    Phi    = pi/2
                    Thetam_gr = Theta0_gr/sin(Phi)
                    w_n_gr = 4.621076207881561
                    F = (Thetam_gr)*sin(w_n_gr*Data_tiempo + Phi);
                    grid on 
                    hold on
                    plot(app.UIAxes,Data_tiempo,yData_gr_rad,'ro',Data_tiempo,F,'b',Tiempo_gr,PosicionODE_gr,'g')
                    legend(app.UIAxes,'D. expe','Sol. Aprox','Simulik');
                    Area_DataExpe_P   = abs(trapz(Data_tiempo,yData_gr_rad));
                    Area_DataODE_P    = abs(trapz(PosicionODE_gr));
                    ErrorODE_P        = abs(((Area_DataODE_P - Area_DataExpe_P)/Area_DataODE_P)*100)
                    app.ErrorSolSimuTextArea.Value = string(ErrorODE_P)
                end
            end
            if Datos == 3
                if Angulo == 2
                    Phi    = pi/2
                    Thetam_pq = Theta0_pq/sin(Phi)
                    w_n_pq = 5.513438707261346
                    Rapidez = (Thetam_pq)*w_n_pq*cos(w_n_pq*Data_tiempo + Phi);
                    grid on 
                    hold on
                    plot(app.UIAxes,Data_tiempo,Data_Rapidez_pq_rad ,'ro',Data_tiempo,Rapidez,'b',Tiempo_pq,RapidezODE_pq,'g')
                    legend(app.UIAxes,'D. expe','Sol. Aprox','Simulik');
                    Area_DataExpe_V   = abs(trapz(Data_tiempo,Data_Rapidez_pq_rad));
                    Area_DataODE_V    = abs(trapz(Tiempo_pq,RapidezODE_pq));
                    ErrorODE_V        = abs(((Area_DataODE_V - Area_DataExpe_V)/Area_DataODE_V)*100)
                    app.ErrorSolSimuTextArea.Value = string(ErrorODE_V)

                end
                if Angulo == 3
                    Phi    = pi/2
                    Thetam_gr = Theta0_gr/sin(Phi)
                    w_n_gr = 4.621076207881561
                    Rapidez = (Thetam_gr)*w_n_gr*cos(w_n_gr*Data_tiempo + Phi);
                    grid on
                    hold on
                    plot(app.UIAxes,Data_tiempo,Data_Rapidez_gr_rad ,'ro',Data_tiempo,Rapidez,'b',Tiempo_gr,RapidezODE_gr,'g')
                    legend(app.UIAxes,'D. expe','Sol. Aprox','Simulik');
                    Area_DataExpe_V   = abs(trapz(Data_tiempo,Data_Rapidez_gr_rad));
                    Area_DataODE_V    = abs(trapz(RapidezODE_gr));
                    ErrorODE_V        = abs(((Area_DataODE_V - Area_DataExpe_V)/Area_DataODE_V)*100)
                    app.ErrorSolSimuTextArea.Value = string(ErrorODE_V)
                end
            end  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DatosDropDownLabel = uilabel(app.UIFigure);
            app.DatosDropDownLabel.Text = 'Datos';
            app.DatosDropDown = uidropdown(app.UIFigure);
            app.DatosDropDown.Items = {'Seleccion', 'Posicion', 'Velocidad'};
            app.DatosDropDown.ItemsData = [1 2 3];
            app.DatosDropDown.ValueChangedFcn = createCallbackFcn(app, @DatosDropDownValueChanged, true);
            app.DatosDropDown.Value = 1;
            app.LeerdatosButton = uibutton(app.UIFigure, 'push');
            app.LeerdatosButton.ButtonPushedFcn = createCallbackFcn(app, @LeerdatosButtonPushed, true);
            app.LeerdatosButton.Text = 'Leer datos';
            app.AnguloDropDownLabel = uilabel(app.UIFigure);
            app.AnguloDropDownLabel.Text = 'Angulo';
            app.AnguloDropDown = uidropdown(app.UIFigure);
            app.AnguloDropDown.Items = {'Seleccionar ', 'Pequeño', 'Grande'};
            app.AnguloDropDown.ItemsData = [1 2 3];
            app.AnguloDropDown.ValueChangedFcn = createCallbackFcn(app, @AnguloDropDownValueChanged, true);
            app.AnguloDropDown.Value = 1;
            app.SolAproxButton = uibutton(app.UIFigure, 'push');
            app.SolAproxButton.ButtonPushedFcn = createCallbackFcn(app, @SolAproxButtonPushed, true);
            app.SolAproxButton.Text = 'Sol. Aprox';
            app.SolSimulinkButton = uibutton(app.UIFigure, 'push');
            app.SolSimulinkButton.ButtonPushedFcn = createCallbackFcn(app, @SolSimulinkButtonPushed, true);
            app.SolSimulinkButton.Text = 'Sol. Simulink';
            app.AmplitudTextAreaLabel = uilabel(app.UIFigure);
            app.AmplitudTextAreaLabel.Text = 'Amplitud ';
            app.AmplitudTextArea = uitextarea(app.UIFigure);
            app.AngInicialTextAreaLabel = uilabel(app.UIFigure);
            app.AngInicialTextAreaLabel.Text = 'Ang. Inicial';
            app.AngInicialTextArea = uitextarea(app.UIFigure);
            app.FreAngularTextAreaLabel = uilabel(app.UIFigure);
            app.FreAngularTextAreaLabel.Text = 'Fre.  Angular';
            app.FreAngularTextArea = uitextarea(app.UIFigure);
            app.ErrorSolAproxTextAreaLabel = uilabel(app.UIFigure);
            app.ErrorSolAproxTextAreaLabel.Text = 'Error. Sol. Aprox';
            app.ErrorSolAproxTextArea = uitextarea(app.UIFigure);
            app.ErrorSolSimuTextAreaLabel = uilabel(app.UIFigure);
            app.ErrorSolSimuTextAreaLabel.Text = 'Error. Sol. Simu';
            app.ErrorSolSimuTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Pendulo_Simple

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end